classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure matlab.ui.Figure         % UI Figure
        Button   matlab.ui.control.Button % start
        Button2  matlab.ui.control.Button % 0
        Button3  matlab.ui.control.Button % 30
        Button4  matlab.ui.control.Button % 60
        Button5  matlab.ui.control.Button % 90
        Button6  matlab.ui.control.Button % 120
        Button7  matlab.ui.control.Button % close
        Button8  matlab.ui.control.Button % 150
        Button9  matlab.ui.control.Button % 180
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            delete(instrfind);
            global s
            s=serial('com4');
            fopen(s);
            
        end

        % Button2 button pushed function
        function Button2ButtonPushed(app)
            global s
            fprintf(s,'a');
        end

        % Button3 button pushed function
        function Button3ButtonPushed(app)
             global s
            fprintf(s,'b');
        end

        % Button4 button pushed function
        function Button4ButtonPushed(app)
             global s
            fprintf(s,'c');
        end

        % Button5 button pushed function
        function Button5ButtonPushed(app)
             global s
            fprintf(s,'d');
        end

        % Button6 button pushed function
        function Button6ButtonPushed(app)
             global s
            fprintf(s,'e');
        end

        % Button8 button pushed function
        function Button8ButtonPushed(app)
             global s
            fprintf(s,'f');
        end

        % Button9 button pushed function
        function Button9ButtonPushed(app)
             global s
            fprintf(s,'g');
        end

        % Button7 button pushed function
        function Button7ButtonPushed(app)
            fclose(s);
            delete(s);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2ButtonPushed);
            app.Button2.Text = '0';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3ButtonPushed);
            app.Button3.Text = '30';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4ButtonPushed);
            app.Button4.Text = '60';
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5ButtonPushed);
            app.Button5.Text = '90';
            app.Button6 = uibutton(app.UIFigure, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @Button6ButtonPushed);
            app.Button6.Text = '120';
            app.Button8 = uibutton(app.UIFigure, 'push');
            app.Button8.ButtonPushedFcn = createCallbackFcn(app, @Button8ButtonPushed);
            app.Button8.Text = '150';
            app.Button9 = uibutton(app.UIFigure, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @Button9ButtonPushed);
            app.Button9.Text = '180';
            app.Button7 = uibutton(app.UIFigure, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @Button7ButtonPushed);
            app.Button7.Text = 'close';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'start';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end